classdef node < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AddNewConnectionButton  matlab.ui.control.Button
        ConnectionsLabel        matlab.ui.control.Label
        AddressEditField        matlab.ui.control.EditField
        AddressEditFieldLabel   matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        Connections = struct();
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Connections.default = table("Address", "In/Out", "Last Seen", "Works Sent", "Works Completed");
            app.Connections.status = containers.Map();
            UITableDisplayDataChanged(app);
        end

        % Button pushed function: AddNewConnectionButton
        function AddNewConnectionButtonPushed(app, event)
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            app.UITable.Data = app.Connections.default;
            connectionKeys = keys(app.Connections.status);
            if isempty(connectionKeys)
                return;
            end
            connectionsStruct = struct();
            for connectionKey = connectionKeys
                connection = app.Connections.status(connectionKey);
            end
            app.UITable.Data = struct2table(connectionsStruct);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.AddressEditFieldLabel = uilabel(app.UIFigure);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.UIFigure, 'text');
            app.ConnectionsLabel = uilabel(app.UIFigure);
            app.ConnectionsLabel.Text = 'Connections';
            app.AddNewConnectionButton = uibutton(app.UIFigure, 'push');
            app.AddNewConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewConnectionButtonPushed, true);
            app.AddNewConnectionButton.Text = 'Add New Connection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = node

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end