classdef nodeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DDMSUIFigure            matlab.ui.Figure
        CreateNewProjectButton  matlab.ui.control.Button
        DistributeSelectedProjectButton  matlab.ui.control.Button
        ProjectsListBox         matlab.ui.control.ListBox
        ProjectsListBoxLabel    matlab.ui.control.Label
        AddNewConnectionButton  matlab.ui.control.Button
        ConnectionsLabel        matlab.ui.control.Label
        AddressEditField        matlab.ui.control.EditField
        AddressEditFieldLabel   matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        loopTimer

        server
        nodeProperties
        nodeMap
        networkConfig
        benchmarkConfig

        projects
    end
    
    methods (Access = private)
        function setInteraction(app, value)
            app.AddNewConnectionButton.Enable = ~value;
            if (true == value)
            else
            end
        end
        
        function Loop(app, ~, ~, ~)
            % Check connections
            for key = keys(app.nodeMap)
                node = app.nodeMap(key);
                % Try to reconnect to servers
                if ("server" == node('type') && false == node("connected"))
                    nodeAddress = node('address');
                    nodePort = node('port');
                    node('socket') = tcpclient(nodeAddress, nodePort, "Timeout", 5);
                end

                % Check new data from clients
                %app.nodeMap(key) = node; % do we need this?
            end
            display(datetime);
            UITableDisplayDataChanged(app);
        end
        
        function stopLoop(app)
            stop(app.loopTimer);
        end
    end
    
    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setInteraction(false);
            app.nodeMap = containers.Map();

            app.nodeProperties = struct();
            app.projects = findProjects();
            app.ProjectsListBoxValueChanged();

            app.server = setupServer();
            
            app.loopTimer = timer('Period', 5, 'ExecutionMode', 'fixedSpacing', 'TasksToExecute', Inf);
            app.loopTimer.TimerFcn = @app.Loop;
            start(app.loopTimer);
            app.setInteraction(true);
        end

        % Button pushed function: AddNewConnectionButton
        function AddNewConnectionButtonPushed(app, event)
            app.setInteraction(false);
            node = containers.Map();
            node('address') = app.AddressEditField.Value;
            node('port') = 6666;
            node('connected') = false;
            node('type') = "server";
            app.nodeMap(node('address')) = node;
            app.setInteraction(true);
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            addresses = [];
            lastseens = [];

%             connectionsTable = table('VariableNames', {'Address', 'LastSeen', 'Works Sent', 'Works Completed'});
%             
%             clientIndex = 1;
%             for key = keys(clientMap)
%                 client = clientMap(key);
%                 addresses(clientIndex) = client('address'); 
%                 lastseens(clientIndex) = client('lastSeen');
%                 clientIndex = clientIndex + 1;
%             end
%             connectionsTable.(1) = addresses';
%             connectionsTable.(1) = lastseens';
%             app.UITable.Data = connectionsTable;
        end

        % Close request function: DDMSUIFigure
        function DDMSUIFigureCloseRequest(app, event)
            app.stopLoop();
            clear app.server;
            delete(app);
        end

        % Button pushed function: DistributeSelectedProjectButton
        function DistributeSelectedProjectButtonPushed(app, event)
            app.setInteraction(false);
            app.nodeProperties.Distribute = true;
            app.nodeProperties.Project = app.ProjectsListBox.Value;
            app.setInteraction(true);
        end

        % Button pushed function: CreateNewProjectButton
        function CreateNewProjectButtonPushed(app, event)
            app.setInteraction(false);
            app.setInteraction(true);
        end

        % Value changed function: ProjectsListBox
        function ProjectsListBoxValueChanged(app, event)
            app.ProjectsListBox.Items = keys(app.projects);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DDMSUIFigure = uifigure('Visible', 'off');
            app.DDMSUIFigure.Name = 'DDMS';
            app.DDMSUIFigure.CloseRequestFcn = createCallbackFcn(app, @DDMSUIFigureCloseRequest, true);
            app.UITable = uitable(app.DDMSUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.AddressEditFieldLabel = uilabel(app.DDMSUIFigure);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.DDMSUIFigure, 'text');
            app.ConnectionsLabel = uilabel(app.DDMSUIFigure);
            app.ConnectionsLabel.Text = 'Connections';
            app.AddNewConnectionButton = uibutton(app.DDMSUIFigure, 'push');
            app.AddNewConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewConnectionButtonPushed, true);
            app.AddNewConnectionButton.Text = 'Add New Connection';
            app.ProjectsListBoxLabel = uilabel(app.DDMSUIFigure);
            app.ProjectsListBoxLabel.Text = 'Projects';
            app.ProjectsListBox = uilistbox(app.DDMSUIFigure);
            app.ProjectsListBox.Items = {};
            app.ProjectsListBox.ValueChangedFcn = createCallbackFcn(app, @ProjectsListBoxValueChanged, true);
            app.ProjectsListBox.Value = {};
            app.DistributeSelectedProjectButton = uibutton(app.DDMSUIFigure, 'push');
            app.DistributeSelectedProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DistributeSelectedProjectButtonPushed, true);
            app.DistributeSelectedProjectButton.Text = 'Distribute Selected Project';
            app.CreateNewProjectButton = uibutton(app.DDMSUIFigure, 'push');
            app.CreateNewProjectButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewProjectButtonPushed, true);
            app.CreateNewProjectButton.Text = 'Create New Project...';
            % Show the figure after all components are created
            app.DDMSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nodeGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DDMSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DDMSUIFigure)
        end
    end
end